classdef MRDreco < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MRDRecoUIFigure            matlab.ui.Figure
        DashboardPanel             matlab.ui.container.Panel
        LoadMRDButton              matlab.ui.control.Button
        Lamp1                      matlab.ui.control.Lamp
        ReconstructButton          matlab.ui.control.Button
        Lamp2                      matlab.ui.control.Lamp
        ExportDicomButton          matlab.ui.control.Button
        Lamp3                      matlab.ui.control.Lamp
        ExitButton                 matlab.ui.control.Button
        RecodimensionsPanel        matlab.ui.container.Panel
        XEditFieldLabel            matlab.ui.control.Label
        XEditField                 matlab.ui.control.NumericEditField
        YEditFieldLabel            matlab.ui.control.Label
        YEditField                 matlab.ui.control.NumericEditField
        ZEditFieldLabel            matlab.ui.control.Label
        ZEditField                 matlab.ui.control.NumericEditField
        NREditFieldLabel_2         matlab.ui.control.Label
        NREditField                matlab.ui.control.NumericEditField
        RecoregularizationPanel    matlab.ui.container.Panel
        TVxyzEditFieldLabel        matlab.ui.control.Label
        TVxyzEditField             matlab.ui.control.NumericEditField
        TVtimeEditFieldLabel       matlab.ui.control.Label
        TVtimeEditField            matlab.ui.control.NumericEditField
        WVxyzEditFieldLabel        matlab.ui.control.Label
        WVxyzEditField             matlab.ui.control.NumericEditField
        AbortButton                matlab.ui.control.Button
        RecoProgressGauge          matlab.ui.control.LinearGauge
        ExportProgressGauge        matlab.ui.control.LinearGauge
        ExportNIFTIButton          matlab.ui.control.Button
        Lamp4                      matlab.ui.control.Lamp
        KspacePanel                matlab.ui.container.Panel
        KspaceFig                  matlab.ui.control.UIAxes
        KDim3EditField             matlab.ui.control.NumericEditField
        KDim3Slider                matlab.ui.control.Slider
        KDim4Slider                matlab.ui.control.Slider
        KDim4EditField             matlab.ui.control.NumericEditField
        OrientationSpinnerLabel    matlab.ui.control.Label
        KOrientationSpinner        matlab.ui.control.Spinner
        KxLabel                    matlab.ui.control.Label
        KyLabel                    matlab.ui.control.Label
        ButtonGroup                matlab.ui.container.ButtonGroup
        fillingButton              matlab.ui.control.RadioButton
        averagesButton             matlab.ui.control.RadioButton
        kvaluesButton              matlab.ui.control.RadioButton
        KDim3Label                 matlab.ui.control.Label
        KEchoSlider                matlab.ui.control.Slider
        EchoLabel                  matlab.ui.control.Label
        KEchoEditField             matlab.ui.control.NumericEditField
        KDim4Label                 matlab.ui.control.Label
        CoilSpinnerLabel           matlab.ui.control.Label
        CoilSpinner                matlab.ui.control.Spinner
        KFlipAngleSlider           matlab.ui.control.Slider
        FlipangleLabel             matlab.ui.control.Label
        KFlipAngleEditField        matlab.ui.control.NumericEditField
        ScanparametersPanel        matlab.ui.container.Panel
        NREditFieldLabel           matlab.ui.control.Label
        NRViewField                matlab.ui.control.NumericEditField
        NEEditFieldLabel           matlab.ui.control.Label
        NEViewField                matlab.ui.control.NumericEditField
        KMatrixViewField2          matlab.ui.control.NumericEditField
        KMatrixViewField3          matlab.ui.control.NumericEditField
        FOVViewField2              matlab.ui.control.NumericEditField
        FOVViewField3              matlab.ui.control.NumericEditField
        MXLabel                    matlab.ui.control.Label
        MYLabel                    matlab.ui.control.Label
        MZLabel                    matlab.ui.control.Label
        MatrixEditFieldLabel       matlab.ui.control.Label
        KMatrixViewField1          matlab.ui.control.NumericEditField
        FOVmmLabel                 matlab.ui.control.Label
        FOVViewField1              matlab.ui.control.NumericEditField
        TRmsLabel                  matlab.ui.control.Label
        TRViewField                matlab.ui.control.NumericEditField
        TEmsLabel                  matlab.ui.control.Label
        TEViewField                matlab.ui.control.NumericEditField
        NALabel                    matlab.ui.control.Label
        NAViewField                matlab.ui.control.NumericEditField
        NFALabel                   matlab.ui.control.Label
        NFAViewField               matlab.ui.control.NumericEditField
        FAdegEditFieldLabel        matlab.ui.control.Label
        FAViewField                matlab.ui.control.EditField
        SequenceEditFieldLabel     matlab.ui.control.Label
        SequenceViewField          matlab.ui.control.EditField
        MRDfileLabel               matlab.ui.control.Label
        MRDfileViewField           matlab.ui.control.EditField
        MessagesPanel              matlab.ui.container.Panel
        MessageBox                 matlab.ui.control.TextArea
        ReconstructionPanel        matlab.ui.container.Panel
        RecoFig                    matlab.ui.control.UIAxes
        Dim3Slider                 matlab.ui.control.Slider
        Dim5Slider                 matlab.ui.control.Slider
        Dim4Slider                 matlab.ui.control.Slider
        Dim3EditField              matlab.ui.control.NumericEditField
        Dim5EditField              matlab.ui.control.NumericEditField
        Dim4EditField              matlab.ui.control.NumericEditField
        OrientationSpinnerLabel_2  matlab.ui.control.Label
        OrientationSpinner         matlab.ui.control.Spinner
        XLabel                     matlab.ui.control.Label
        YLabel                     matlab.ui.control.Label
        Dim6EditField              matlab.ui.control.NumericEditField
        Dim6Slider                 matlab.ui.control.Slider
        Dim3Label                  matlab.ui.control.Label
        Dim4Label                  matlab.ui.control.Label
        LevelSlider                matlab.ui.control.Slider
        WindowLabel                matlab.ui.control.Label
        LevelLabel                 matlab.ui.control.Label
        WindowSlider               matlab.ui.control.Slider
        LevelViewField             matlab.ui.control.NumericEditField
        WindowViewField            matlab.ui.control.NumericEditField
        SetButton                  matlab.ui.control.Button
        PlayButton                 matlab.ui.control.Button
        StopButton                 matlab.ui.control.Button
        Dim5Label                  matlab.ui.control.Label
        Dim6Label                  matlab.ui.control.Label
        AutoIntensityCheckBox      matlab.ui.control.CheckBox
        AutolevelLabel             matlab.ui.control.Label
        MovieImage                 matlab.ui.control.Image
        TitleLabel                 matlab.ui.control.Label
        TulpImage                  matlab.ui.control.Image
        GustavStrijkersAmsterdamUMCVersion12Label  matlab.ui.control.Label
        MRSImage                   matlab.ui.control.Image
    end


    % ------------------------------------------------------
    %
    % MRDreco
    %
    %
    % Gustav Strijkers
    % Amsterdam UMC
    % g.j.strijkers@amsterdamumc.nl
    %
    %
    % Version 1.0
    %
    % - initial commit
    %
    % Version 1.1
    %
    % - custom k-space trajectories in 3D
    % - NIFTI export for 3D data
    % - DICOM export improved
    %
    % Version 1.1
    %
    % - preparing for multi-coil data (untested so far)
    %
    % Version 1.2
    %
    % - radial reconstruction
    %
    % 
    %


    properties (Access = private)
        
        appdir                  % Application directory
        olddir                  % Starting directory
        bart_detected           % Bart toolbox detected true or false
        mrd_import_path         % MRD import path
        dicom_export_path       % Dicom export path
        nifti_export_path       % NIFTI export path
        valid_file              % Valid data file true or false
        valid_reco              % Valid reconstruction true or false
        valid_movie             % Data with multiple repetitions true or false
        seqpar                  % Sequence parameters
        stop_reco               % Stop the reco true or false
        more_echoes             % More than 1 echo true or false
        more_repetitions        % More than 1 repetition true or false
        more_flipangles         % More than 1 flip angle true or false
        proud_kspace_traj       % Proud 3D user k-space trajectory true or false
        mul_kspace_traj         % 2D k-space trajectory true or false
        scanner_kspace_traj     % Scanner k-space trajectory (rtable.rtv) true or false
        twoDdata                % 2D cartesian data true or false
        threeDdata              % 3D cartesian data true or false
        radialData              % 2D radial data true or false
        flipangles              % Flip angle array
        kspace                  % k-space data
        ukspace                 % unsorted k-space data
        images                  % Reconstructed images
        nsa_space               % Nubmer of signal averages per k-point
        filling_space           % K-space points acquired (1) or not (0)
        image_orient            % image orientation permutation matrix
        kspace_orient_label     % Orientation labels in k-space figure
        image_orient_label      % Orientation labels in image figure
        cbar                    % kspace averages and filling colorbar
        reco_matrix             % Reconstructed image size
        level                   % Image intensity level
        window                  % Image intensity window
        stopmovie               % Movie running (false) or stopping (true)
        validNIFTIdata          % Data suitable for NIFTI export true or false
        multicoil               % Multicoil data true or false
        ncoils                  % Number of coils
        
    end


    methods (Access = private)
        
        
        % --------------------------------------------------------------
        %
        %                     LIGHTS AND BUTTONS 
        %
        % --------------------------------------------------------------
        
        function SetLights(app,code)
            
            if ~strcmp(code{1},'Null') app.Lamp1.Color = code{1}; end
            if ~strcmp(code{2},'Null') app.Lamp2.Color = code{2}; end
            if ~strcmp(code{3},'Null') app.Lamp3.Color = code{3}; end
            if ~strcmp(code{4},'Null') app.Lamp4.Color = code{4}; end
            drawnow;
            
        end
        
        
        function result = GetLights(app)
            
            result{1} = app.Lamp1.Color;
            result{2} = app.Lamp2.Color;
            result{3} = app.Lamp3.Color;
            result{4} = app.Lamp4.Color;
            
        end
        
        
        function SetButtons(app,code)
            
            if ~strcmp(code{1},'Null') app.LoadMRDButton.Enable = code{1}; end
            if ~strcmp(code{2},'Null') app.ReconstructButton.Enable = code{2}; end
            if ~strcmp(code{3},'Null') app.ExportDicomButton.Enable = code{3}; end
            if ~strcmp(code{4},'Null') app.ExportNIFTIButton.Enable = code{4}; end
            if ~strcmp(code{5},'Null') app.ExitButton.Enable = code{5}; end
            drawnow;
            
        end
        
        
        function result = GetButtons(app)
            
            result{1} = app.LoadMRDButton.Enable;
            result{2} = app.ReconstructButton.Enable;
            result{3} = app.ExportDicomButton.Enable;
            result{4} = app.ExportNIFTIButton.Enable;
            result{5} = app.ExitButton.Enable;
            
        end


        % --------------------------------------------------------------
        %
        %                           PARAMETERS
        %
        % --------------------------------------------------------------
        
        
        function SetParametersFcn(app)
            
            app.TextMessage('Setting k-space parameters ....');
            
            % 2D or 3D data
            app.twoDdata = false;
            app.threeDdata = false;
            app.ZEditField.Enable = 'off';
            if isfield(app.seqpar,'NO_VIEWS_2')
                if app.seqpar.NO_VIEWS_2>1
                    app.TextMessage('3D data ...');
                    app.threeDdata = true;
                    app.ZEditField.Enable = 'on';
                else
                    app.TextMessage('2D data ...');
                    app.twoDdata = true;
                end
            end
            
            % check for radial data acquisition
            app.radialData = false;
            if isfield(app.seqpar,'radial_on')
                if app.seqpar.radial_on == 1
                    app.radialData = true;
                    % center the echo for radial acquistions
                    for i = 1:app.ncoils
                        app.kspace{i} = center_radial_echo(app,app.kspace{i});
                    end
                end
            end
            
            % More than 1 flip angle
            app.flipangles = app.seqpar.alpha;
            if isfield(app.seqpar, 'VFA_size')
                if app.seqpar.VFA_size > 0
                    app.TextMessage('Variable flip-angle data detected ...');
                    app.more_flipangles = true;
                    app.flipangles = app.seqpar.VFA_angles(1:app.seqpar.VFA_size);
                end
            end
            app.seqpar.flipanglearray = app.flipangles;
            
            % More than 1 echo
            if isfield(app.seqpar, 'NO_ECHOES')
                if app.seqpar.NO_ECHOES > 1
                    app.TextMessage('Multi-echo data detected ...');
                    app.more_echoes = true;
                end
            else
                app.seqpar.NO_ECHOES = 1;
            end
            
            % More than 1 repetition
            if app.seqpar.EXPERIMENT_ARRAY>1
                if app.more_flipangles
                    app.seqpar.EXPERIMENT_ARRAY = app.seqpar.EXPERIMENT_ARRAY/app.seqpar.VFA_size;
                end
                if app.seqpar.EXPERIMENT_ARRAY>1
                    app.TextMessage('Multiple repetitions detected ...');
                    app.more_repetitions = true;
                end
            end
            
            % Check for scanner k-space table (RARE sequence)
            app.scanner_kspace_traj = false;
            if exist(strcat(app.mrd_import_path,filesep,'rtable.rtv'), 'file') == 2
                app.scanner_kspace_traj = true;
                app.TextMessage('Scanner k-space table detected ...');
            end
            
            % User k-space trajectory
            app.mul_kspace_traj = false;
            app.proud_kspace_traj = false;
            if isfield(app.seqpar, 'pe1_order')
                if app.seqpar.pe1_order==3
                    if isfield(app.seqpar,'gp_var_proud')
                        app.proud_kspace_traj = true;
                        app.TextMessage('3D user k-space trajectory ...');
                    else 
                        app.mul_kspace_traj = true;
                        app.TextMessage('2D user k-space trajectory ...');
                    end
                end
            end
            
            % Textfile k-space trajectory
            if isfield(app.seqpar, 'pe1_order')
                if app.seqpar.pe1_order==3
                    flist = dir(fullfile(app.mrd_import_path,'*.txt'));
                    if ~isempty(flist)
                        app.seqpar.gp_var_proud = load([flist(1).folder,filesep,flist(1).name]);
                        app.proud_kspace_traj = true;
                    end
                end
            end
            
            % Show some parameters in app
            app.SequenceViewField.Value = app.seqpar.PPL;
            app.TRViewField.Value = app.seqpar.tr;
            app.TEViewField.Value = app.seqpar.te;
            app.NAViewField.Value = app.seqpar.NO_AVERAGES;
            app.NRViewField.Value = app.seqpar.EXPERIMENT_ARRAY;
            app.NEViewField.Value = app.seqpar.NO_ECHOES;
            app.FAViewField.Value = ['  ',num2str(app.flipangles)];
            app.NFAViewField.Value = length(app.flipangles);
            app.seqpar.aratio = app.seqpar.FOVf/8;
            
            % Matrix size
            if app.threeDdata
                
                % 3D data
                app.MZLabel.Text = 'Z';
                app.ZEditFieldLabel.Text = 'Z';
                
                % Matrix dimensions
                app.KMatrixViewField1.Value = app.seqpar.NO_SAMPLES;
                app.KMatrixViewField2.Value = app.seqpar.NO_VIEWS;
                app.KMatrixViewField3.Value = app.seqpar.NO_VIEWS_2;
                
                % Permute data to (X, Y, Z, other)
                for i=1:app.ncoils
                    
                    switch ndims(app.kspace{i})
                        
                        case 3
                            
                            app.kspace{i} = permute(app.kspace{i},[3,1,2]);
                            
                        case 4
                            
                            app.kspace{i} = permute(app.kspace{i},[4,2,3,1]);
                            
                        case 5
                            
                            app.kspace{i} = permute(app.kspace{i},[5,3,4,1,2]);
                            
                        case 6
                            
                            app.kspace{i} = permute(app.kspace{i},[6,4,5,1,2,3]);
                            
                    end
               
                end
                
                % Field of view
                app.FOVViewField1.Value = app.seqpar.FOV;
                app.FOVViewField2.Value = app.seqpar.FOV*app.seqpar.aratio;
                app.FOVViewField3.Value = app.seqpar.SLICE_THICKNESS;
              
            end
            
            if app.twoDdata
                
                % 2D data or radial data
                app.MZLabel.Text = 'Slices';
                app.ZEditFieldLabel.Text = 'Slices';
                
                % Matrix dimensions
                app.KMatrixViewField1.Value = app.seqpar.NO_SAMPLES;
                app.KMatrixViewField2.Value = app.seqpar.NO_VIEWS;
                app.KMatrixViewField3.Value = app.seqpar.NO_SLICES;
                
                % Permute data to (X, Y, Z, NR, NFA, NE)
                for i=1:app.ncoils
                    
                    switch ndims(app.kspace{i})
                        
                        case 2
                            
                            app.kspace{i} = permute(app.kspace{i},[2,1]);
                        
                        case 3
                            
                            if app.seqpar.NO_SLICES > 1
                                app.kspace{i} = permute(app.kspace{i},[3,2,1]);
                            else
                                app.kspace{i} = permute(app.kspace{i},[3,2,4,1]);
                            end
                            
                        case 4
                            if app.seqpar.NO_SLICES > 1
                                app.kspace{i} = permute(app.kspace{i},[4,3,2,1]);
                            else
                                app.kspace{i} = permute(app.kspace{i},[4,3,5,1,2]);
                            end
                            
                        case 5
                            
                            app.kspace{i} = permute(app.kspace{i},[5,4,3,1,2]);
                            
                        case 6
                            
                            app.kspace{i} = permute(app.kspace{i},[6,5,4,1,2,3]);
                            
                    end
                    
                end
                
                % Field of view
                app.FOVViewField1.Value = app.seqpar.FOV;
                app.FOVViewField2.Value = app.seqpar.FOV*app.seqpar.aratio;
                app.FOVViewField3.Value = app.seqpar.SLICE_THICKNESS;
               
            end
            
            % Permute data to (X, Y, Z, NR, NFA, NE)    ---- NOT FULLY TESTED -----
            for i=1:app.ncoils
                
                if ndims(app.kspace{i})==4 && app.more_flipangles app.kspace{i} = permute(app.kspace{i},[1,2,3,5,4,6]); end
                if ndims(app.kspace{i})==4 && app.more_echoes app.kspace{i} = permute(app.kspace{i},[1,2,3,5,6,4]); end
                if ndims(app.kspace{i})==5 && app.more_echoes && app.more_flipangles app.kspace{i} = permute(app.kspace{i},[1,2,3,6,4,5]); end
                if ndims(app.kspace{i})==5 && app.more_echoes && app.more_repetitions app.kspace{i} = permute(app.kspace{i},[1,2,3,4,6,5]); end
                if ndims(app.kspace{i})==5 && app.more_repetitions && app.more_flipangles app.kspace{i} = permute(app.kspace{i},[1,2,3,4,5,6]); end
            
            end
            
            % Image orientations
            if app.threeDdata
                app.image_orient = [1 2 3 4 5 6; 2 3 1 4 5 6 ; 3 1 2 4 5 6 ; 4 1 2 3 5 6 ; 4 2 3 1 5 6 ; 4 3 1 2 5 6 ; 1 2 4 3 5 6; 2 3 4 1 5 6 ; 3 1 4 2 5 6 ; 1 2 3 6 4 5 ; 1 3 2 6 4 5 ; 2 3 1 6 4 5];
                app.kspace_orient_label = ["Kx","Ky","Kz","NR","FA","Echo" ; "Ky","Kz","Kx","NR","FA","Echo" ; "Kz","Kx","Ky","NR","FA","Echo" ; "NR","Kx","Ky","Kz","FA","Echo" ; "NR","Ky","Kz","Kx","FA","Echo" ; "NR","Kz","Kx","Ky","FA","Echo" ; "Kx","Ky","NR","Kz","FA","Echo" ; "Ky","Kz","NR","Kx","FA","Echo" ; "Kz","Kx","NR","Ky","FA","Echo" ; "Kx","Ky","Kz","Echo","NR","FA" ; "Kx","Kz","Ky","Echo","NR","FA" ; "Ky","Kz","Kx","Echo","NR","FA"];
                app.image_orient_label = ["X","Y","Z","NR","FA","Echo" ; "Y","Z","X","NR","FA","Echo" ; "Z","X","Y","NR","FA","Echo" ; "NR","X","Y","Z","FA","Echo" ; "NR","Y","Z","X","FA","Echo" ; "NR","Z","X","Y","FA","Echo" ; "X","Y","NR","Z","FA","Echo" ; "Y","Z","NR","X","FA","Echo" ; "Z","X","NR","Y","FA","Echo" ; "X","Y","Z","Echo","NR","FA" ; "X","Z","Y","Echo","NR","FA" ; "Y","Z","X","Echo","NR","FA"];
            else
                app.image_orient = [1 2 3 4 5 6; 2 3 1 4 5 6 ; 3 1 2 4 5 6 ; 4 1 2 3 5 6 ; 4 2 3 1 5 6 ; 4 3 1 2 5 6 ; 1 2 4 3 5 6; 2 3 4 1 5 6 ; 3 1 4 2 5 6 ; 1 2 3 6 4 5 ; 1 3 2 6 4 5 ; 2 3 1 6 4 5];
                app.kspace_orient_label = ["Kx","Ky","Slice","NR","FA","Echo" ; "Ky","Slice","Kx","NR","FA","Echo" ; "Slice","Kx","Ky","NR","FA","Echo" ; "NR","Kx","Ky","Slice","FA","Echo" ; "NR","Ky","Slice","Kx","FA","Echo" ; "NR","Slice","Kx","Ky","FA","Echo" ; "Kx","Ky","NR","Slice","FA","Echo" ; "Ky","Slice","NR","Kx","FA","Echo" ; "Slice","Kx","NR","Ky","FA","Echo" ; "Kx","Ky","Slice","Echo","NR","FA" ; "Kx","Slice","Ky","Echo","NR","FA" ; "Ky","Slice","Kx","Echo","NR","FA"];
                app.image_orient_label = ["X","Y","Slice","NR","FA","Echo" ; "Y","Slice","X","NR","FA","Echo" ; "Slice","X","Y","NR","FA","Echo"; "NR","X","Y","Slice","FA","Echo" ; "NR","Y","Slice","X","FA","Echo" ; "NR","Slice","X","Y","FA","Echo" ; "X","Y","NR","Slice","FA","Echo" ; "Y","Slice","NR","X","FA","Echo" ; "Slice","X","NR","Y","FA","Echo" ; "X","Y","Slice","Echo","NR","FA" ; "X","Slice","Y","Echo","NR","FA" ; "Y","Slice","X","Echo","NR","FA"];
            end
            app.OrientationSpinner.Value = 1;
            app.KOrientationSpinner.Value = 1;
            
            % Reco size
            app.XEditField.Value = app.KMatrixViewField1.Value;
            app.YEditField.Value = app.KMatrixViewField2.Value;
            app.ZEditField.Value = app.KMatrixViewField3.Value;
            app.NREditField.Value = app.NRViewField.Value;
            
        end % SetParametersFcn


        function SetKspaceLimitsFcn(app)
        
            app.TextMessage('Setting k-space parameter limits ....');
            
            % Coil spinner
            if app.ncoils>1
                app.CoilSpinner.Value = 1;
                app.CoilSpinner.Limits = [1 app.ncoils];
                app.CoilSpinner.Enable = 'on';
            else
                app.CoilSpinner.Value = 1;
                app.CoilSpinner.Limits = [1 2];
                app.CoilSpinner.Enable = 'off';
            end
            
            % Dimension 1 selection
            im = permute(abs(app.kspace{app.CoilSpinner.Value}),app.image_orient(app.KOrientationSpinner.Value,:));
            dim1size = size(im,3);
            app.KDim3EditField.Value = 1;
            app.KDim3Slider.Value = 1;
            if dim1size > 1
                app.KDim3EditField.Limits = [1,dim1size];
                app.KDim3Slider.Limits = [1,dim1size];
                app.KDim3EditField.Value = round(dim1size/2);
                app.KDim3Slider.Value = round(dim1size/2);
                app.KDim3EditField.Enable = 'on';
                app.KDim3Slider.Enable = 'on';
            else
                app.KDim3EditField.Limits = [1,2];
                app.KDim3Slider.Limits = [1,2];
                app.KDim3EditField.Enable = 'off';
                app.KDim3Slider.Enable = 'off';
            end
            
            % Dimension 2 selection
            dim2size = size(im,4);
            app.KDim4EditField.Value = 1;
            app.KDim4Slider.Value = 1;
            if dim2size > 1
                app.KDim4EditField.Limits = [1,dim2size];
                app.KDim4Slider.Limits = [1,dim2size];
                app.KDim4EditField.Value = round(dim2size/2);
                app.KDim4Slider.Value = round(dim2size/2);
                app.KDim4EditField.Enable = 'on';
                app.KDim4Slider.Enable = 'on';
            else
                app.KDim4EditField.Limits = [1,2];
                app.KDim4Slider.Limits = [1,2];
                app.KDim4EditField.Enable = 'off';
                app.KDim4Slider.Enable = 'off';
            end
            
            % Echo selection
            app.KEchoEditField.Value = 1;
            app.KEchoSlider.Value = 1;
            if app.more_echoes
                app.KEchoEditField.Limits = [1,app.NEViewField.Value];
                app.KEchoSlider.Limits = [1,app.NEViewField.Value];
                app.KEchoEditField.Enable = 'on';
                app.KEchoSlider.Enable = 'on';
            else
                app.KEchoEditField.Limits = [1,2];
                app.KEchoSlider.Limits = [1,2];
                app.KEchoEditField.Enable = 'off';
                app.KEchoSlider.Enable = 'off';
            end
            
            % Flip angle selection
            app.KFlipAngleEditField.Value = 1;
            app.KFlipAngleSlider.Value = 1;
            if app.more_flipangles
                app.KFlipAngleEditField.Limits = [1,app.NFAViewField.Value];
                app.KFlipAngleSlider.Limits = [1,app.NFAViewField.Value];
                app.KFlipAngleEditField.Enable = 'on';
                app.KFlipAngleSlider.Enable = 'on';
                
            else
                app.KFlipAngleEditField.Limits = [1,2];
                app.KFlipAngleSlider.Limits = [1,2];
                app.KFlipAngleEditField.Enable = 'off';
                app.KFlipAngleSlider.Enable = 'off';
            end
            
            % Label the orientations
            app.KxLabel.Text = app.kspace_orient_label(app.KOrientationSpinner.Value,1);
            app.KyLabel.Text = app.kspace_orient_label(app.KOrientationSpinner.Value,2);
            app.KDim3Label.Text = app.kspace_orient_label(app.KOrientationSpinner.Value,3);
            app.KDim4Label.Text = app.kspace_orient_label(app.KOrientationSpinner.Value,4);
            
        end % SetKspaceLimitsFcn


        function SetImageLimitsFcn(app)
        
            app.TextMessage('Setting image parameter limits ....');
            
            % Dimension 3 selection
            im = permute(abs(app.images),app.image_orient(app.OrientationSpinner.Value,:));
            dim3size = size(im,3);
            app.Dim3EditField.Value = 1;
            app.Dim3Slider.Value = 1;
            if dim3size > 1
                app.Dim3EditField.Limits = [1,dim3size];
                app.Dim3Slider.Limits = [1,dim3size];
                app.Dim3EditField.Value = round(dim3size/2);
                app.Dim3Slider.Value = round(dim3size/2);
                app.Dim3EditField.Enable = 'on';
                app.Dim3Slider.Enable = 'on';
            else
                app.Dim3EditField.Limits = [1,2];
                app.Dim3Slider.Limits = [1,2];
                app.Dim3EditField.Enable = 'off';
                app.Dim3Slider.Enable = 'off';
            end
            
            % Dimension 4 selection
            dim4size = size(im,4);
            app.Dim4EditField.Value = 1;
            app.Dim4Slider.Value = 1;
            if dim4size > 1
                app.Dim4EditField.Limits = [1,dim4size];
                app.Dim4Slider.Limits = [1,dim4size];
                app.Dim4EditField.Value = round(dim4size/2);
                app.Dim4Slider.Value = round(dim4size/2);
                app.Dim4EditField.Enable = 'on';
                app.Dim4Slider.Enable = 'on';
            else
                app.Dim4EditField.Limits = [1,2];
                app.Dim4Slider.Limits = [1,2];
                app.Dim4EditField.Enable = 'off';
                app.Dim4Slider.Enable = 'off';
            end
            
            % Dimension 5 selection
            dim5size = size(im,5);
            app.Dim5EditField.Value = 1;
            app.Dim5Slider.Value = 1;
            if dim5size > 1
                app.Dim5EditField.Limits = [1,dim5size];
                app.Dim5Slider.Limits = [1,dim5size];
                app.Dim5EditField.Enable = 'on';
                app.Dim5Slider.Enable = 'on';
            else
                app.Dim5EditField.Limits = [1,2];
                app.Dim5Slider.Limits = [1,2];
                app.Dim5EditField.Enable = 'off';
                app.Dim5Slider.Enable = 'off';
            end
            
            % Flip angle selection
            dim6size = size(im,6);
            app.Dim6EditField.Value = 1;
            app.Dim6Slider.Value = 1;
            if dim6size > 1
                app.Dim6EditField.Limits = [1,dim6size];
                app.Dim6Slider.Limits = [1,dim6size];
                app.Dim6EditField.Enable = 'on';
                app.Dim6Slider.Enable = 'on';
                
            else
                app.Dim6EditField.Limits = [1,2];
                app.Dim6Slider.Limits = [1,2];
                app.Dim6EditField.Enable = 'off';
                app.Dim6Slider.Enable = 'off';
            end
            
            % Label the orientations
            app.XLabel.Text = app.image_orient_label(app.OrientationSpinner.Value,1);
            app.YLabel.Text = app.image_orient_label(app.OrientationSpinner.Value,2);
            app.Dim3Label.Text = app.image_orient_label(app.OrientationSpinner.Value,3);
            app.Dim4Label.Text = app.image_orient_label(app.OrientationSpinner.Value,4);
            app.Dim5Label.Text = app.image_orient_label(app.OrientationSpinner.Value,5);
            app.Dim6Label.Text = app.image_orient_label(app.OrientationSpinner.Value,6);
            
        end % SetImageLimitsFcn


        function FillKspaceFcn(app)
        
         app.TextMessage('Filling k-space ....');
        
            if app.valid_file
                
                app.nsa_space = app.seqpar.NO_AVERAGES * ones(size(app.kspace{1}));
                app.filling_space = ones(size(app.kspace{1}));
                
                % Scanner k-space table (rtable.rtv)
                if app.scanner_kspace_traj
                    
                    scanner_kspace_table = load(strcat(app.mrd_import_path,filesep,'rtable.rtv'));
                    
                    for i=1:app.ncoils
                        app.kspace{i} = sortscannerkspaceMRD(app,app.kspace{i},app.seqpar,scanner_kspace_table);
                    end
                    
                end
                
                % 2D user trajectory
                if app.mul_kspace_traj
                    
                    % resize matrix to dimensions found in the user table
                    app.seqpar.NO_VIEWS_ORIG = app.seqpar.NO_VIEWS;
                    maxk = max([app.seqpar.gp_var_mul,-app.seqpar.gp_var_mul]);
                    if rem(maxk,2)==0
                        app.seqpar.NO_VIEWS = 2*maxk;
                    else
                        app.seqpar.NO_VIEWS = 2*(maxk+1);
                    end
                    app.YEditField.Value = app.seqpar.NO_VIEWS;
                    app.KMatrixViewField2.Value = app.seqpar.NO_VIEWS;
                    app.seqpar.NO_SAMPLES_ORIG = app.seqpar.NO_SAMPLES;
                    
                    % sort
                    for i=1:app.ncoils
                        [app.kspace{i},app.nsa_space,app.filling_space] = sort2DkspaceMRD(app,app.seqpar,app.ukspace{i},app.NRViewField.Value);
                    end
                       
                end
                
                
                % 3D PROUD user k-space
                if app.proud_kspace_traj
              
                    % resize matrix to dimensions found in the proud table
                    app.seqpar.NO_VIEWS_ORIG = app.seqpar.NO_VIEWS;
                    app.seqpar.NO_VIEWS_2_ORIG = app.seqpar.NO_VIEWS_2;
                    app.seqpar.proudarray = reshape(app.seqpar.gp_var_proud,[2,length(app.seqpar.gp_var_proud)/2]);
                    maxky = max([app.seqpar.proudarray(1,:),-app.seqpar.proudarray(1,:)]);
                    
                    %scatter(app.seqpar.proudarray(1,:),app.seqpar.proudarray(2,:));
                    
                    if rem(maxky,2)==0
                        app.seqpar.NO_VIEWS = 2*maxky;
                    else
                        app.seqpar.NO_VIEWS = 2*(maxky+1);
                    end
                    maxkz = max([app.seqpar.proudarray(2,:),-app.seqpar.proudarray(2,:)]);
                    if rem(maxkz,2)==0
                        app.seqpar.NO_VIEWS_2 = 2*maxkz;
                    else
                        app.seqpar.NO_VIEWS_2 = 2*(maxkz+1);
                    end
                    app.YEditField.Value = app.seqpar.NO_VIEWS;
                    app.ZEditField.Value = app.seqpar.NO_VIEWS_2;
                    app.KMatrixViewField2.Value = app.seqpar.NO_VIEWS;
                    app.KMatrixViewField3.Value = app.seqpar.NO_VIEWS_2;
                    
                    % sort
                    for i=1:app.ncoils
                        [app.kspace{i},app.nsa_space,app.filling_space] = sortPROUDkspaceMRD(app,app.seqpar,app.ukspace{i},app.NRViewField.Value);
                    end
                   
                end
                
                % Chop of the navigator if present
                if isfield(app.seqpar, 'slice_nav')
                    if app.seqpar.slice_nav == 1
                        discard = 4*app.seqpar.no_samples_nav;
                        for i=1:app.ncoils
                            app.kspace{i} = app.kspace{i}(discard+1:end,:,:,:,:,:);
                        end
                        app.nsa_space = app.nsa_space(discard+1:end,:,:,:,:,:);
                        app.filling_space = app.filling_space(discard+1:end,:,:,:,:,:);
                        app.seqpar.NO_SAMPLES = app.seqpar.NO_SAMPLES - discard;
                        app.XEditField.Value = app.seqpar.NO_SAMPLES;
                        app.KMatrixViewField1.Value = app.seqpar.NO_SAMPLES;
                    end
                end
                
            
            end
            
        end % FillKspaceFcn


        % --------------------------------------------------------------
        %
        %                           FIGURES 
        %
        % --------------------------------------------------------------
        
        
        function result = CalcKAspectratioFnc(app,dim1label,dim2label,sizex,sizey)
            
            if strcmp(dim1label,'Kx') L1 = app.FOVViewField1.Value; end
            if strcmp(dim1label,'Ky') L1 = app.FOVViewField2.Value; end
            if strcmp(dim1label,'Kz') L1 = app.FOVViewField3.Value; end
            if strcmp(dim1label,'NR') L1 = mean([app.FOVViewField1.Value,app.FOVViewField2.Value]); end
            if strcmp(dim1label,'Slice') L1 = app.FOVViewField3.Value*app.KMatrixViewField3.Value; end
            
            if strcmp(dim2label,'Kx') L2 = app.FOVViewField1.Value; end
            if strcmp(dim2label,'Ky') L2 = app.FOVViewField2.Value; end
            if strcmp(dim2label,'Kz') L2 = app.FOVViewField3.Value; end
            if strcmp(dim2label,'NR') L2 = mean([app.FOVViewField1.Value,app.FOVViewField2.Value]); end
            if strcmp(dim2label,'Slice') L2 = app.FOVViewField3.Value*app.KMatrixViewField3.Value; end
            
            result = (L2/L1)*(sizex/sizey);
            
        end % Aspectratio
        
        
        function result = CalcAspectratioFnc(app,dim1label,dim2label,sizex,sizey)
            
            if strcmp(dim1label,'X') L1 = app.FOVViewField1.Value; end
            if strcmp(dim1label,'Y') L1 = app.FOVViewField2.Value; end
            if strcmp(dim1label,'Z') L1 = app.FOVViewField3.Value; end
            if strcmp(dim1label,'NR') L1 = mean([app.FOVViewField1.Value,app.FOVViewField2.Value]); end
            if strcmp(dim1label,'Slice') L1 = app.FOVViewField3.Value*app.KMatrixViewField3.Value; end
            
            if strcmp(dim2label,'X') L2 = app.FOVViewField1.Value; end
            if strcmp(dim2label,'Y') L2 = app.FOVViewField2.Value; end
            if strcmp(dim2label,'Z') L2 = app.FOVViewField3.Value; end
            if strcmp(dim2label,'NR') L2 = mean([app.FOVViewField1.Value,app.FOVViewField2.Value]); end
            if strcmp(dim2label,'Slice') L2 = app.FOVViewField3.Value*app.KMatrixViewField3.Value; end
            
            result = (L2/L1)*(sizex/sizey);
                        
        end % Aspectratio
                
        
        function SetIntensity(app)
            
            if app.valid_reco
                
                app.LevelSlider.Limits = [0 max(app.images(:))];
                app.WindowSlider.Limits = [0 max(app.images(:))];
                
                app.level = max(app.images(:))/3;
                app.window = max(app.images(:))/1.5;
                
                app.LevelSlider.Value = app.level;
                app.WindowSlider.Value = app.window;
                app.LevelViewField.Value = round(app.level);
                app.WindowViewField.Value = round(app.window);
                
            end
        
        end % SetIntensity
                
        
        function PlotKspaceDataFig(app)
            
            if app.valid_file
                
                cla(app.KspaceFig);
                delete(app.cbar);
                hold(app.KspaceFig,'on');
                
                % Select the data
                if app.fillingButton.Value == 1 
                    im = app.filling_space; 
                    cmap = [0 0 0 ; summer(1)];
                    scale = max(im(:));
                end
                
                if app.averagesButton.Value == 1 
                    im = app.nsa_space; 
                    cmap = [0 0 0 ; summer(max(im(:)))]; 
                    scale = max(im(:));
                    app.cbar = colorbar(app.KspaceFig,'location','eastoutside','TickLabelsMode','auto','FontSize',12);
                end
                
                if app.kvaluesButton.Value == 1 
                    im = abs(app.kspace{app.CoilSpinner.Value}); 
                    cmap = gray;
                    scale = 0.1*max(im(:));
                end
              
                % Plot the image in the image panel
                app.KspaceFig.Position = [34,39,325,325];
                
                % Select which slice to show
                im = permute(im,app.image_orient(app.KOrientationSpinner.Value,:));
                im = squeeze(im(:,:,app.KDim3EditField.Value,app.KDim4EditField.Value,app.KFlipAngleEditField.Value,app.KEchoEditField.Value));
               
                % Calculate aspect ratio
                recoaspectratio = CalcKAspectratioFnc(app,app.KxLabel.Text,app.KyLabel.Text,size(im,1),size(im,2));
                
                xlim(app.KspaceFig,[0, size(im,1) + 1]);
                ylim(app.KspaceFig,[0, size(im,2) + 1]);
                
                % Plot the image in the k-space images panel
                imshow(rot90(im),[0 scale],'ColorMap',cmap,'InitialMagnification','fit','Parent',app.KspaceFig);
                daspect(app.KspaceFig,[recoaspectratio 1 1]);
                
                drawnow;
                                
                hold(app.KspaceFig,'off');
                drawnow;
                
            end
            
            
        end % PlotKspaceDataFig
        
            
        function PlotImageDataFig(app)
            
            cla(app.RecoFig);
            hold(app.RecoFig,'on');
            
            if app.valid_reco
                
                % Select the data
                im = app.images;
                
                % Select which slice to show
                im = permute(im,app.image_orient(app.OrientationSpinner.Value,:));
                im = squeeze(im(:,:,app.Dim3EditField.Value,app.Dim4EditField.Value,app.Dim5EditField.Value,app.Dim6EditField.Value));
                
                % Plot the image in the image panel
                app.RecoFig.Position = [43,171,325,325];
                
                if app.AutoIntensityCheckBox.Value == 1
                    
                    th = graythresh(mat2gray(im))*max(im(:));
                    masked = im(im>th);
                    
                    app.level = 1.5*mean(masked(:));
                    if app.level>4095 app.level = 4095; end
                    app.window = 3*mean(masked(:));
                    if app.window>4095 app.window = 4095; end
                    
                    app.LevelSlider.Value = app.level;
                    app.WindowSlider.Value = app.window;
                    app.LevelViewField.Value = round(app.level);
                    app.WindowViewField.Value = round(app.window);
                end
                
                % Calculate aspect ratio
                recoaspectratio = CalcAspectratioFnc(app,app.XLabel.Text,app.YLabel.Text,size(im,1),size(im,2));
                
                xlim(app.RecoFig,[0, size(im,1) + 1]);
                ylim(app.RecoFig,[0, size(im,2) + 1]);
                
                % Plot the image in the k-space images panel
                imshow(rot90(im),[app.level-app.window/2 app.level+app.window/2],'InitialMagnification','fit','Parent',app.RecoFig);
                daspect(app.RecoFig,[recoaspectratio 1 1]);
                drawnow;
                          
            end
             
            hold(app.RecoFig,'off');
            
        end % PlotImageDataFig


    end
    
    
    % --------------------------------------------------------------
    %
    %                       PUBLIC FUNCTIONS
    %
    % --------------------------------------------------------------
    
    methods (Access = public)
        
        function TextMessage(app, message)
            
            % Text messages in the app
            app.MessageBox.Value = [message ; app.MessageBox.Value];
            drawnow;
            
        end % TextMessage
        
    end


    % --------------------------------------------------------------
    %
    %                   USER INTERFACE CODE
    %
    % --------------------------------------------------------------
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Empty file import and export paths
            app.mrd_import_path = '';
            app.dicom_export_path = '';
            
            app.stopmovie = true;
            
            % Start the parallel pool if it is not running already
            % p = gcp('nocreate');
            % if isempty(p)
            %    parpool;
            % end
            
            % Add subfolders to search path
            if (~isdeployed)
                app.olddir = pwd;
                info = dir(which('MRDreco.mlapp'));
                app.appdir = info.folder;
                cd(app.appdir);
                addpath(genpath(pwd));
            end
            
            % Add the bart path to the environment variable if available
            app.bart_detected = false;
            if isfile('bartpath.txt')
                app.bart_detected = true;
                app.TextMessage('BART toolbox detected ...');
                setenv('TOOLBOX_PATH', fileread('bartpath.txt'));
            end
            
            % Show the Amsterdam UMC logo
            app.TulpImage.ImageSource = [app.appdir,filesep,'AmsterdamUMC.gif'];
            app.MRSImage.ImageSource = [app.appdir,filesep,'MRS.gif'];
            app.MovieImage.ImageSource = [app.appdir,filesep,'Movie.gif'];
            
            % Suppress unneccesary warnings
            warning off;
            
            % Initialize buttons
            app.SetLights({'Red','Red','Red','Red'});
            app.SetButtons({'On','Off','Off','Off','On'});
            
        end

        % Button pushed function: LoadMRDButton
        function LoadMRDButtonPushed(app, event)
            
        
            % Set buttons
            app.SetButtons({'Off','Off','Off','Off','Off'});
            app.SetLights({'Yellow','Null','Null','Null'});
            
            % Select 1 MRD file which contains multiple flip-angles data
            app.MRDRecoUIFigure.Visible = 'off';
            [mrdfile,app.mrd_import_path] = uigetfile(strcat(app.mrd_import_path,filesep,'*.MRD'));
            app.MRDRecoUIFigure.Visible = 'on';
            
            % Initial settings
            app.valid_file = false;
            app.valid_reco = false;
            app.threeDdata = false;
            app.proud_kspace_traj = false;
            app.more_echoes = false;
            app.more_repetitions = false;
            app.more_flipangles = false;
            app.stopmovie = true;
            
            if ischar(mrdfile)
                
                % MRD file selected
                app.MRDfileViewField.Value = mrdfile;
                mrdfile = [app.mrd_import_path,mrdfile];
                flist = dir(fullfile(app.mrd_import_path,'*.MRD'));
                
                % Load the data
                app.TextMessage('Reading MRD file(s) ...');
                
                app.multicoil = false; 
                app.ncoils = length(flist);
                if app.ncoils>1 
                    app.multicoil = true; 
                    app.TextMessage('Multi receiver coil data detected ...');
                end
                
                app.kspace = {};
                for i=1:app.ncoils
                    app.TextMessage(strcat('Loading coil #',num2str(i)));
                    [app.kspace{i},~,app.seqpar,app.ukspace{i}] = Get_mrd_3D5(fullfile(flist(i).folder,flist(i).name),'seq','cen');
                end
                
                % Flag for valid data
                app.valid_file = true;
                
                % Determine the sequence file name
                app.seqpar.PPL = replace(app.seqpar.PPL,'\',filesep);
                [~,name,ext] = fileparts(app.seqpar.PPL);
                app.seqpar.PPL = strcat(name,ext);
                
                disp(size(app.kspace{1}))
                
            end
            
            % Report on valid/invalid file selection
            if app.valid_file
                
                % Set other parameters
                app.SetParametersFcn;
                app.SetKspaceLimitsFcn;
                app.FillKspaceFcn;
                app.PlotKspaceDataFig;
                
                app.TextMessage('Data import ready ...');
                app.TextMessage(strcat('Size of k-space =',{' '},num2str(size(app.kspace{1}))));
                
                % Set buttons
                app.SetLights({'Green','Red','Red','Red'});
                app.SetButtons({'On','On','Null','Null','On'});
          
            else                
                
                % Not a valid data file 
                uialert(app.MRDRecoUIFigure,'Please select a valid data file','Error');
                
                % Set the buttons for invalid data file 
                app.SetLights({'Red','Red','Red','Red'});
                app.SetButtons({'On','Off','Off','Off','On'});
                
            end
            
        end

        % Value changed function: KFlipAngleEditField
        function KFlipAngleEditFieldValueChanged(app, event)
            
            app.KFlipAngleSlider.Value = app.KFlipAngleEditField.Value;
            app.PlotKspaceDataFig;
            
        end

        % Value changed function: KFlipAngleSlider
        function KFlipAngleSliderValueChanged(app, event)
            
            app.KFlipAngleEditField.Value = round(app.KFlipAngleSlider.Value);
            app.PlotKspaceDataFig;
            
        end

        % Value changed function: KDim4EditField
        function KDim4EditFieldValueChanged(app, event)
            
            app.KDim4Slider.Value = app.KDim4EditField.Value;
            app.PlotKspaceDataFig;
            
        end

        % Value changed function: KDim4Slider
        function KDim4SliderValueChanged(app, event)
            
            app.KDim4EditField.Value = round(app.KDim4Slider.Value);
            app.PlotKspaceDataFig;
            
        end

        % Value changed function: KEchoEditField
        function KEchoEditFieldValueChanged(app, event)
            
            app.KEchoSlider.Value = app.KEchoEditField.Value;
            app.PlotKspaceDataFig;
            
        end

        % Value changed function: KEchoSlider
        function KEchoSliderValueChanged(app, event)
            
            app.KEchoEditField.Value = round(app.KEchoSlider.Value);
            app.PlotKspaceDataFig;
            
        end

        % Value changed function: KDim3EditField
        function KDim3EditFieldValueChanged(app, event)
            
            app.KDim3Slider.Value = app.KDim3EditField.Value;
            app.PlotKspaceDataFig;
            
        end

        % Value changed function: KDim3Slider
        function KDim3SliderValueChanged(app, event)
            
            app.KDim3EditField.Value = round(app.KDim3Slider.Value);
            app.PlotKspaceDataFig;
            
        end

        % Value changed function: KOrientationSpinner
        function KOrientationSpinnerValueChanged(app, event)
            
            app.SetKspaceLimitsFcn;
            app.PlotKspaceDataFig;
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            
            app.PlotKspaceDataFig;
            
        end

        % Value changed function: CoilSpinner
        function CoilSpinnerValueChanged(app, event)
            
            app.PlotKspaceDataFig;
            
        end

        % Value changed function: NREditField
        function NREditFieldValueChanged(app, event)
                    
            % Set buttons
            app.SetLights({'Yellow','Null','Null','Null'});
            app.SetButtons({'Off','Off','Off','Off','Off'});
            
            app.stopmovie = true;
                        
            if app.proud_kspace_traj
                
                % Re-sort the data in desired number of frames
                for i=1:app.ncoils
                    [app.kspace{i},app.nsa_space,app.filling_space] = sortPROUDkspaceMRD(app,app.seqpar,app.ukspace{i},app.NREditField.Value);
                end
                
            end
            
            % sort
            if app.mul_kspace_traj
                
                % Re-sort the data in desired number of frames
                for i=1:app.ncoils
                    [app.kspace{i},app.nsa_space,app.filling_space] = sort2DkspaceMRD(app,app.seqpar,app.ukspace{i},app.NREditField.Value);
                end
                
                % Chop of the navigator if present
                if isfield(app.seqpar, 'slice_nav')
                    if app.seqpar.slice_nav == 1
                        discard = 4*app.seqpar.no_samples_nav;
                        for i=1:app.ncoils
                            app.kspace{i} = app.kspace{i}(discard+1:end,:,:,:,:,:);
                        end
                        app.nsa_space = app.nsa_space(discard+1:end,:,:,:,:,:);
                        app.filling_space = app.filling_space(discard+1:end,:,:,:,:,:);
                        app.seqpar.NO_SAMPLES = size(app.kspace{1},1);
                        app.XEditField.Value = app.seqpar.NO_SAMPLES;
                        app.KMatrixViewField1.Value = app.seqpar.NO_SAMPLES;
                    end
                end
                
            end
            
            app.SetKspaceLimitsFcn;
            app.PlotKspaceDataFig;
            
            % Set buttons
            app.SetLights({'Green','Red','Red','Red'});
            app.SetButtons({'On','On','Off','Off','On'});
            app.RecoProgressGauge.Value = 0;
            app.ExportProgressGauge.Value = 0;
            drawnow;
            
        end

        % Value changed function: ZEditField
        function RecoSizeFieldValueChanged(app, event)
            
            app.stopmovie = true;
            
            % Set buttons
            app.SetLights({'Null','Red','Red','Red'});
            app.SetButtons({'On','On','Off','Off','On'});
            app.RecoProgressGauge.Value = 0;
            app.ExportProgressGauge.Value = 0;
            drawnow;
            
        end

        % Button pushed function: ReconstructButton
        function ReconstructButtonPushed(app, event)
            
            if app.valid_file
                
                % Set buttons
                app.SetButtons({'Off','Off','Off','Off','Off'});
                app.SetLights({'Null','Yellow','Null','Null'});
                
                app.stop_reco = false;
                app.valid_reco = false;
                app.validNIFTIdata = false;
                app.valid_movie = false;
                app.stopmovie = true;
                app.AbortButton.Enable = 'on';
                app.RecoProgressGauge.Value = 0;
                drawnow;
                
                if ~app.proud_kspace_traj
                    
                    app.TextMessage('Reconstructing the data ...');
                    
                    % Images = (X, Y, Z, NR, NFA, NE)
                    app.images = zeros(app.XEditField.Value,app.YEditField.Value,app.ZEditField.Value,app.NREditField.Value,app.NFAViewField.Value,app.NEViewField.Value);
                    
                    % Intitate progress gauage
                    progress_cnt = 0;
                    if app.threeDdata
                        totalcounter = app.NFAViewField.Value*app.NEViewField.Value;
                    else
                        totalcounter = app.NFAViewField.Value*app.NEViewField.Value*app.KMatrixViewField3.Value;
                    end
                                        
                    % Loop over flip-angles and echo-times
                    fa_cnt = 0;
                    while fa_cnt<app.NFAViewField.Value && ~app.stop_reco
                        
                        fa_cnt = fa_cnt + 1;
                        te_cnt = 0;
                        
                        while te_cnt<app.NEViewField.Value && ~app.stop_reco
                            
                            te_cnt = te_cnt + 1;
                                                                                   
                            % Reconstruction part
                            
                            if app.twoDdata && ~app.radialData
                                % 2D multi-slice cartesian reco
                                
                                slice_cnt = 0;
                                while slice_cnt<app.ZEditField.Value && ~app.stop_reco
                                    
                                    slice_cnt = slice_cnt + 1;
                                    
                                    % report progress
                                    app.TextMessage(strcat('Reconstructing slice #',num2str(slice_cnt),', flip-angle #',num2str(fa_cnt),', echo #',num2str(te_cnt),' ...'));
                                    app.RecoProgressGauge.Value = round(100*progress_cnt/totalcounter);
                                    progress_cnt = progress_cnt + 1;
                                    drawnow;
                                    
                                    % do the reco, x and y dimensions seem to be permuted after 2D reco
                                    for i=1:app.ncoils
                                        recokspace{i} = app.kspace{i}(:,:,slice_cnt,:,fa_cnt,te_cnt);
                                    end
                                    
                                    app.images(:,:,slice_cnt,:,fa_cnt,te_cnt) = recoMRD_mc(app,recokspace,app.ncoils,app.WVxyzEditField.Value,app.TVxyzEditField.Value,app.TVtimeEditField.Value,app.XEditField.Value,app.YEditField.Value,1,app.NREditField.Value);
                                    app.valid_reco = true;
                                    
                                    drawnow;
                                
                                end
                              
                            end
                            
                            if app.threeDdata
                                % 3D cartesian reco
                                
                                % Report on progress
                                app.TextMessage(strcat('Reconstructing flip-angle #',num2str(fa_cnt),', echo #',num2str(te_cnt),' ...'));
                                app.RecoProgressGauge.Value = round(100*progress_cnt/totalcounter);
                                progress_cnt = progress_cnt + 1;
                                drawnow;
                                
                                % 3D reco
                                for i=1:app.ncoils
                                    recokspace{i} = app.kspace{i}(:,:,:,:,fa_cnt,te_cnt);
                                end
                                app.images(:,:,:,:,fa_cnt,te_cnt) = recoMRD_mc(app,recokspace,app.ncoils,app.WVxyzEditField.Value,app.TVxyzEditField.Value,app.TVtimeEditField.Value,app.XEditField.Value,app.YEditField.Value,app.ZEditField.Value,app.NREditField.Value);
                                app.valid_reco = true;
                                app.validNIFTIdata = true;
                                
                            end
                            
                            if app.twoDdata && app.radialData
                                % 2D multi-slice radial reco
                                
                                slice_cnt = 0;
                                while slice_cnt<app.ZEditField.Value && ~app.stop_reco
                                    
                                    slice_cnt = slice_cnt + 1;
                                    
                                    % report progress
                                    app.TextMessage(strcat('Reconstructing slice #',num2str(slice_cnt),', flip-angle #',num2str(fa_cnt),', echo #',num2str(te_cnt),' ...'));
                                    app.RecoProgressGauge.Value = round(100*progress_cnt/totalcounter);
                                    progress_cnt = progress_cnt + 1;
                                    drawnow;
                                    
                                    % do the reco, x and y dimensions seem to be permuted after 2D reco
                                    for i=1:app.ncoils
                                        recokspace{i} = app.kspace{i}(:,:,slice_cnt,:,fa_cnt,te_cnt);
                                    end
                                    
                                    app.images(:,:,slice_cnt,:,fa_cnt,te_cnt) = recoMRDradial_mc(app,recokspace,app.ncoils,app.WVxyzEditField.Value,app.TVxyzEditField.Value,app.TVtimeEditField.Value,app.XEditField.Value,app.YEditField.Value,1,app.NREditField.Value);
                                    app.valid_reco = true;
                                    
                                    drawnow;
                                    
                                end
                                
                            end
                            
                        end
                                               
                        app.RecoProgressGauge.Value = 100;
                        drawnow;
                        
                    end
                    
                else
                    
                    app.TextMessage('Reconstructing the user kspace-data ...');
                    app.TextMessage('Warning: Multiple flip-angle and echo data not supported (yet) ...');
                    app.TextMessage('Warning: 2D data not supported (yet) ...');
                    
                    % Images = (X, Y, Z, NR, NFA, NE)
                    app.images = zeros(app.XEditField.Value,app.YEditField.Value,app.ZEditField.Value,app.NREditField.Value,1,1);
                    
                    % Re-sort the data in desired number of frames
                   % for i=1:app.ncoils
                   %%     [app.kspace{i},app.nsa_space,app.filling_space] = sortPROUDkspaceMRD(app,app.seqpar,app.ukspace{i},app.NREditField.Value);
                   % end
                    
                    % Reconstruction part
                    if app.threeDdata
                        
                        % 3D reco
                        for i=1:app.ncoils
                            recokspace{i} = app.kspace{i}(:,:,:,:,1,1);
                        end
                        app.images(:,:,:,:,1,1) = recoMRD_mc(app,recokspace,app.ncoils,app.WVxyzEditField.Value,app.TVxyzEditField.Value,app.TVtimeEditField.Value,app.XEditField.Value,app.YEditField.Value,app.ZEditField.Value,app.NREditField.Value);
                        app.valid_reco = true;
                        app.validNIFTIdata = true;
                        app.RecoProgressGauge.Value = 100;
                        drawnow;
                        
                    else
                        
                        % not implemented yet, or maybe never
                        
                    end
                    
                end
                
                % Readout oversampling
                if isfield(app.seqpar, 'oversample')
                    if app.seqpar.oversample == 1
                        oversample = round(app.XEditField.Value/4);
                        app.images = app.images(oversample+1:end-oversample,:,:,:,:,:);
                    end
                end
                
                % Phase orientation  --- only in the last step, I think
                % with DICOM export
                %if isfield(app.seqpar, 'PHASE_ORIENTATION')
                %    if app.seqpar.PHASE_ORIENTATION == 1
                %        app.images = permute(app.images,[2 1 3 4 5 6]);
                %        app.images = flip(app.images,1);
                %    end
                %end
                
                disp(size(app.images))
                
            end
            
            if app.valid_reco
                
                % Show the images
                app.reco_matrix = size(app.images);
                app.images = round(4096*app.images/max(app.images(:)));
                
                if size(app.images,4)>1 app.valid_movie = true; end
                app.SetImageLimitsFcn;
                app.SetIntensity;
                app.PlotImageDataFig;
                app.TextMessage(strcat('Size of image =',{' '},num2str(size(app.images))));
                
                if app.stop_reco
                    app.SetLights({'Null',rgb(Color,'orange'),'Red','Red'});
                else
                    app.SetLights({'Null','Green','Red','Red'}); 
                end
                app.SetButtons({'On','On','On','On','On'});
                app.ExportProgressGauge.Value = 0;
                 
            end
            
        end

        % Button pushed function: AbortButton
        function AbortButtonPushed(app, event)
            
            app.stop_reco = true;
            app.AbortButton.Enable = 'off';
            app.TextMessage('Reco will be aborted ...');
            app.TextMessage('Please wait ...');
            drawnow;
        
        end

        % Value changed function: Dim3EditField
        function Dim3EditFieldValueChanged(app, event)
            
            app.Dim3Slider.Value = app.Dim3EditField.Value;
            app.PlotImageDataFig;
            
        end

        % Value changed function: Dim3Slider
        function Dim3SliderValueChanged(app, event)
            
            app.Dim3EditField.Value = round(app.Dim3Slider.Value);
            app.PlotImageDataFig;
            
        end

        % Value changed function: Dim4EditField
        function Dim4EditFieldValueChanged(app, event)
            
            app.Dim4Slider.Value = app.Dim4EditField.Value;
            app.PlotImageDataFig;
            
        end

        % Value changed function: Dim4Slider
        function Dim4SliderValueChanged(app, event)
            
            app.Dim4EditField.Value = round(app.Dim4Slider.Value);
            app.PlotImageDataFig;
            
        end

        % Value changed function: Dim5EditField
        function Dim5EditFieldValueChanged(app, event)
          
            app.Dim5Slider.Value = app.Dim5EditField.Value;
            app.PlotImageDataFig;
            
        end

        % Value changed function: Dim5Slider
        function Dim5SliderValueChanged(app, event)
            
            app.Dim5EditField.Value = round(app.Dim5Slider.Value);
            app.PlotImageDataFig;
            
        end

        % Value changed function: Dim6EditField
        function Dim6EditFieldValueChanged(app, event)
            
            app.Dim6Slider.Value = app.Dim6EditField.Value;
            app.PlotImageDataFig;
            
        end

        % Value changed function: Dim6Slider
        function Dim6SliderValueChanged(app, event)
            
            app.Dim6EditField.Value = round(app.Dim6Slider.Value);
            app.PlotImageDataFig;
                        
        end

        % Value changed function: OrientationSpinner
        function OrientationSpinnerValueChanged(app, event)
            
            app.SetImageLimitsFcn;
            app.PlotImageDataFig;
            
        end

        % Value changed function: LevelSlider
        function LevelSliderValueChanged(app, event)
            
            if app.valid_reco
                
                app.LevelViewField.Value = round(app.LevelSlider.Value);
                app.level = app.LevelViewField.Value;
                app.PlotImageDataFig;
                
            end
            
        end

        % Value changed function: WindowSlider
        function WindowSliderValueChanged(app, event)
            
            if app.valid_reco
                
                app.WindowViewField.Value = round(app.WindowSlider.Value);
                app.window = app.WindowViewField.Value;
                app.PlotImageDataFig;
                
            end
            
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            
            if app.valid_reco
                
                app.AutoIntensityCheckBox.Value = 0;
                app.SetIntensity;
                app.PlotImageDataFig;
                
            end
        
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            
            if app.valid_reco
                
                app.PlayButton.Enable = 'off';
                app.StopButton.Enable = 'on';
                app.ExitButton.Enable = 'off';
                app.stopmovie = false;
                
                app.TextMessage('Starting the movie ...');
                
                counter = 1;
                while ~app.stopmovie
                    
                    app.Dim4EditField.Value = counter;
                    app.Dim4Slider.Value = counter;
                    app.PlotImageDataFig;
                    
                    counter = counter + 1;
                    im = permute(abs(app.images),app.image_orient(app.OrientationSpinner.Value,:));
                    if counter>size(im,4) 
                        counter = 1; 
                    end
                    
                end
                
                app.PlayButton.Enable = 'on';
                app.StopButton.Enable = 'off';
                
            end
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            
            app.TextMessage('Stopping the movie ...');
            
            app.PlayButton.Enable = 'on';
            app.StopButton.Enable = 'off';
            app.stopmovie = true;
            app.ExitButton.Enable = 'on';
            drawnow;
            
        end

        % Button pushed function: ExportNIFTIButton
        function ExportNIFTIButtonPushed(app, event)
            
            
            if app.valid_reco && app.validNIFTIdata
                
                % Set buttons
                app.SetButtons({'Off','Off','Off','Off','Off'});
                app.SetLights({'Null','Null','Null','Yellow'});
                
                % Ask for directory
                app.MRDRecoUIFigure.Visible = 'off';
                app.nifti_export_path = uigetdir(app.nifti_export_path,'NIFTI export path');
                app.MRDRecoUIFigure.Visible = 'on';
                drawnow;
                
                % Check if directory exists
                ready = true;
                if ~ischar(app.nifti_export_path)   % user cancel
                    ready = false;
                    app.nifti_export_path = '';
                else
                    [~, Attrib] = fileattrib(app.nifti_export_path);
                    if ~any(Attrib.UserWrite)   % check if directory is writable
                        ready = false;
                    end
                end
                
                % Export to NIFTI
                if ready
                    
                    app.TextMessage('Exporting to NIFTI ...');
                    
                    % Normalize images and determine dimensions
                    image4D = squeeze(app.images(:,:,:,:,1,1));
                    im = round(32767 * image4D / max(image4D(:)));
                    im = cast(im,'single');
                    [dimx,dimy,dimz,nrframes] = size(im);
                    
                    % Set some parameters
                    totalacquisitiontime = app.seqpar.NO_VIEWS*app.seqpar.NO_VIEWS_2*app.seqpar.NO_AVERAGES*app.NRViewField.Value*app.seqpar.tr/1000;
                    app.seqpar.timeperframe = 0;
                    if nrframes>1 app.seqpar.timeperframe = totalacquisitiontime/app.NREditField.Value; end
                    load('niftiinfo.mat');
                    info.Filename = 'image4D.nii';
                    info.Filemoddate = datestr(datetime);
                    info.Datatype = 'single';
                    info.BitsPerPixel = 32;
                    info.Filesize = dimx*dimy*dimz*nrframes*32;
                    if nrframes>1
                        info.ImageSize = [dimx dimy dimz nrframes];
                        info.PixelDimensions = [app.FOVViewField1.Value/dimx app.FOVViewField2.Value/dimy app.FOVViewField3.Value/dimz app.seqpar.timeperframe];
                    else
                        info.ImageSize = [dimx dimy dimz];
                        info.PixelDimensions = [app.FOVViewField1.Value/dimx app.FOVViewField2.Value/dimy app.FOVViewField3.Value/dimz];
                    end
                    info.raw.dim = [3 dimx dimy dimz nrframes 1 1 1 1];
                    info.raw.pixdim = [app.FOVViewField1.Value/dimx app.FOVViewField2.Value/dimy app.FOVViewField3.Value/dimz app.seqpar.timeperframe 0 0 0 0];
                    info.vox_offset = 0;
                    
                    % Export NIFTI
                    niftiwrite(im,[app.nifti_export_path,'/image4D.nii'],info);
                    
                    app.TextMessage('NIFTI export finished ...');
                    
                    % Reset buttons
                    app.SetLights({'Null','Null','Null','Green'});
                    SetButtons(app,{'on','on','on','on','on'});
                    
                else
                    
                    f = app.MRDRecoUIFigure;
                    uialert(f,'Not a valid directory','Error');
                    
                    % Reset buttons
                    app.SetLights({'Null','Null','Null','Red'});
                    SetButtons(app,{'On','On','On','On','On'});
                    
                end
                
            else
                
                app.TextMessage('Data not suitable for NIFTI export ...');
                app.TextMessage('3D/4D data required ...');
                
            end
        
        end

        % Button pushed function: ExportDicomButton
        function ExportDicomButtonPushed(app, event)
            
            % Export DICOM
        
            if app.valid_reco
                
                % Set buttons
                app.SetButtons({'Off','Off','Off','Off','Off'});
                app.SetLights({'Null','Null','Yellow','Null'});
                
                % Ask for directory
                app.MRDRecoUIFigure.Visible = 'off';
                app.dicom_export_path = uigetdir(app.dicom_export_path,'DICOM export path');
                app.MRDRecoUIFigure.Visible = 'on';
                drawnow;
                                
                % Check if directory exists
                ready = true;
                if ~ischar(app.dicom_export_path)   % user cancel
                    ready = false;
                    app.dicom_export_path = '';
                else
                    [~, Attrib] = fileattrib(app.dicom_export_path);
                    if ~any(Attrib.UserWrite)   % check if directory is writable
                        ready = false;
                    end
                end
                
                % If directory is valid and writable
                if ready
                    
                    % Some parameters
                    if app.threeDdata
                        app.seqpar.dimensionality = '3D';
                        app.seqpar.SLICE_SEPARATION = app.FOVViewField3.Value/size(app.images,3);
                        app.seqpar.SLICE_THICKNESS = app.FOVViewField3.Value/size(app.images,3);
                    else
                        app.seqpar.dimensionality = '2D';
                        app.seqpar.SLICE_SEPARATION = app.seqpar.SLICE_SEPARATION/app.seqpar.SLICE_INTERLEAVE;
                        app.seqpar.SLICE_THICKNESS = app.FOVViewField3.Value;
                    end
                    
                    app.seqpar.SLICE_INTERLEAVE = 1;
                    app.seqpar.FOV = app.FOVViewField1.Value;
                    app.seqpar.NO_SLICES = size(app.images,3);
                    app.seqpar.NO_VIEWS = size(app.images,2);
                    app.seqpar.NO_VIEWS2 = size(app.images,3);
                    app.seqpar.field_strength = 7;
                    app.seqpar.filename = 111;
                    totalacquisitiontime = app.seqpar.NO_VIEWS*app.seqpar.NO_VIEWS2*app.seqpar.NO_AVERAGES*app.seqpar.NO_ECHOES*app.NFAViewField.Value*app.NRViewField.Value*app.seqpar.tr/1000;
                    app.seqpar.acqdur = totalacquisitiontime;
                    app.seqpar.timeperframe = totalacquisitiontime/app.NREditField.Value;
                    app.seqpar.nr_frames = app.NREditField.Value;
                    
                    
                    % search for dicom file
                    app.TextMessage('Searching for DICOM information ...');
                    
                    % dcmpath.txt
                    dcm_spath{1} = fileread('dcmpath.txt');
                    dcm_spath{1} = [dcm_spath{1},extractAfter(dcm_spath{1},['Data',filesep])];
                    
                    % Assuming that MRD and Data directory share the same base-directory
                    % Example: base / MRD / 343 / 8455 /
                    
                    % base / data / 343 / DICOM / 8455 /
                    dcm_spath{2} = replace(app.mrd_import_path,'MRD','Data');
                    dcmpos = strfind(dcm_spath{2},filesep);
                    dcmpos = dcmpos(end-1);
                    dcm_spath{2} = insertBefore(dcm_spath{2},dcmpos,[filesep,'DICOM']);
                    
                    % base / data / 343 / DICOM / 8455 / 1 /
                    dcm_spath{3} = [dcm_spath{2},'1',filesep];
                    
                    % base / data / 343 / DICOM / 8455 / 2 /
                    dcm_spath{4} = [dcm_spath{2},'2',filesep];
                    
                    % Check if one of the directories contains dicom files
                    dcm_exist = false;
                    for i=1:length(dcm_spath)
                        if ~isempty(dir([dcm_spath{i},filesep,'*.dcm']))
                            dcm_path = dcm_spath{i};
                            dcm_exist = true;
                            break;
                        end
                    end
                    
                    if dcm_exist
                       
                        % Export image with Dicom information available
                        app.TextMessage('Dicom export ...');
                        
                        % Dicom export
                        export_dicom_DCM(app,dcm_path,app.dicom_export_path,app.images,app.seqpar,'XD');
                        
                    else
                        
                        % Export without image orientation information
                        app.TextMessage('WARNING: DICOM image orientation information not available ...');
                                               
                        % Dicom export
                        export_dicom_MRD(app,app.dicom_export_path,app.images,app.seqpar,'XD');
                        
                    end
                    
                    % Reset buttons
                    app.SetLights({'Null','Null','Green','Null'});
                    app.SetButtons({'On','On','On','On','On'});
                    
                else
                    
                    % Alert
                    uialert(app.MRDRecoUIFigure,'Not a valid directory','Error');
                    
                    % Reset buttons
                    app.SetLights({'Null','Null','Red','Null'});
                    app.SetButtons({'On','On','On','On','On'});
                    
                end
                
            end
        
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            
            app.MRDRecoUIFigureCloseRequest;
            
        end

        % Close request function: MRDRecoUIFigure
        function MRDRecoUIFigureCloseRequest(app, event)
                        
            % Remove subfolders from search path
            if (~isdeployed)
                info = dir(which('MRDreco.mlapp'));
                app.appdir = info.folder;
                rmpath(genpath(app.appdir));
                cd(app.olddir);
            end
            
            % Exit
            app.delete;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MRDRecoUIFigure = uifigure('Visible', 'off');
            app.MRDRecoUIFigure.CloseRequestFcn = createCallbackFcn(app, @MRDRecoUIFigureCloseRequest, true);
            app.DashboardPanel = uipanel(app.MRDRecoUIFigure);
            app.DashboardPanel.Title = 'Dashboard';
            app.LoadMRDButton = uibutton(app.DashboardPanel, 'push');
            app.LoadMRDButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMRDButtonPushed, true);
            app.LoadMRDButton.Text = 'Load MRD';
            app.Lamp1 = uilamp(app.DashboardPanel);
            app.ReconstructButton = uibutton(app.DashboardPanel, 'push');
            app.ReconstructButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructButtonPushed, true);
            app.ReconstructButton.Text = 'Reconstruct';
            app.Lamp2 = uilamp(app.DashboardPanel);
            app.ExportDicomButton = uibutton(app.DashboardPanel, 'push');
            app.ExportDicomButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDicomButtonPushed, true);
            app.ExportDicomButton.Text = 'Export Dicom';
            app.Lamp3 = uilamp(app.DashboardPanel);
            app.ExitButton = uibutton(app.DashboardPanel, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.RecodimensionsPanel = uipanel(app.DashboardPanel);
            app.RecodimensionsPanel.Title = 'Reco dimensions';
            app.XEditFieldLabel = uilabel(app.RecodimensionsPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.RecodimensionsPanel, 'numeric');
            app.XEditField.Limits = [16 1024];
            app.XEditField.RoundFractionalValues = 'on';
            app.XEditField.ValueDisplayFormat = '%.0f';
            app.XEditField.Value = 128;
            app.YEditFieldLabel = uilabel(app.RecodimensionsPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.RecodimensionsPanel, 'numeric');
            app.YEditField.Limits = [16 1024];
            app.YEditField.RoundFractionalValues = 'on';
            app.YEditField.ValueDisplayFormat = '%.0f';
            app.YEditField.Value = 128;
            app.ZEditFieldLabel = uilabel(app.RecodimensionsPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.RecodimensionsPanel, 'numeric');
            app.ZEditField.Limits = [1 1024];
            app.ZEditField.RoundFractionalValues = 'on';
            app.ZEditField.ValueDisplayFormat = '%.0f';
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @RecoSizeFieldValueChanged, true);
            app.ZEditField.Value = 128;
            app.NREditFieldLabel_2 = uilabel(app.RecodimensionsPanel);
            app.NREditFieldLabel_2.Text = 'NR';
            app.NREditField = uieditfield(app.RecodimensionsPanel, 'numeric');
            app.NREditField.Limits = [1 1024];
            app.NREditField.RoundFractionalValues = 'on';
            app.NREditField.ValueDisplayFormat = '%.0f';
            app.NREditField.ValueChangedFcn = createCallbackFcn(app, @NREditFieldValueChanged, true);
            app.NREditField.Value = 1;
            app.RecoregularizationPanel = uipanel(app.DashboardPanel);
            app.RecoregularizationPanel.Title = 'Reco regularization';
            app.TVxyzEditFieldLabel = uilabel(app.RecoregularizationPanel);
            app.TVxyzEditFieldLabel.Text = 'TVxyz';
            app.TVxyzEditField = uieditfield(app.RecoregularizationPanel, 'numeric');
            app.TVxyzEditField.Limits = [0 2];
            app.TVxyzEditField.ValueDisplayFormat = '%.3f';
            app.TVxyzEditField.Value = 0.01;
            app.TVtimeEditFieldLabel = uilabel(app.RecoregularizationPanel);
            app.TVtimeEditFieldLabel.Text = 'TVtime';
            app.TVtimeEditField = uieditfield(app.RecoregularizationPanel, 'numeric');
            app.TVtimeEditField.Limits = [0 2];
            app.TVtimeEditField.ValueDisplayFormat = '%.3f';
            app.WVxyzEditFieldLabel = uilabel(app.RecoregularizationPanel);
            app.WVxyzEditFieldLabel.Text = 'WVxyz';
            app.WVxyzEditField = uieditfield(app.RecoregularizationPanel, 'numeric');
            app.WVxyzEditField.Limits = [0 2];
            app.WVxyzEditField.ValueDisplayFormat = '%.3f';
            app.WVxyzEditField.Value = 0.01;
            app.AbortButton = uibutton(app.DashboardPanel, 'push');
            app.AbortButton.ButtonPushedFcn = createCallbackFcn(app, @AbortButtonPushed, true);
            app.AbortButton.Enable = 'off';
            app.AbortButton.Text = 'abort';
            app.RecoProgressGauge = uigauge(app.DashboardPanel, 'linear');
            app.ExportProgressGauge = uigauge(app.DashboardPanel, 'linear');
            app.ExportNIFTIButton = uibutton(app.DashboardPanel, 'push');
            app.ExportNIFTIButton.ButtonPushedFcn = createCallbackFcn(app, @ExportNIFTIButtonPushed, true);
            app.ExportNIFTIButton.Text = 'Export NIFTI';
            app.Lamp4 = uilamp(app.DashboardPanel);
            app.KspacePanel = uipanel(app.MRDRecoUIFigure);
            app.KspacePanel.Title = 'K-space';
            app.KspaceFig = uiaxes(app.KspacePanel);
            title(app.KspaceFig, '')
            xlabel(app.KspaceFig, '')
            ylabel(app.KspaceFig, '')
            app.KspaceFig.XTick = [];
            app.KspaceFig.YTick = [];
            app.KDim3EditField = uieditfield(app.KspacePanel, 'numeric');
            app.KDim3EditField.Limits = [1 Inf];
            app.KDim3EditField.ValueDisplayFormat = '%.0f';
            app.KDim3EditField.ValueChangedFcn = createCallbackFcn(app, @KDim3EditFieldValueChanged, true);
            app.KDim3EditField.Value = 1;
            app.KDim3Slider = uislider(app.KspacePanel);
            app.KDim3Slider.Limits = [1 200];
            app.KDim3Slider.MajorTicks = [];
            app.KDim3Slider.ValueChangedFcn = createCallbackFcn(app, @KDim3SliderValueChanged, true);
            app.KDim3Slider.MinorTicks = [];
            app.KDim3Slider.Value = 1;
            app.KDim4Slider = uislider(app.KspacePanel);
            app.KDim4Slider.Limits = [1 200];
            app.KDim4Slider.MajorTicks = [];
            app.KDim4Slider.ValueChangedFcn = createCallbackFcn(app, @KDim4SliderValueChanged, true);
            app.KDim4Slider.MinorTicks = [];
            app.KDim4Slider.Value = 1;
            app.KDim4EditField = uieditfield(app.KspacePanel, 'numeric');
            app.KDim4EditField.Limits = [1 Inf];
            app.KDim4EditField.ValueDisplayFormat = '%.0f';
            app.KDim4EditField.ValueChangedFcn = createCallbackFcn(app, @KDim4EditFieldValueChanged, true);
            app.KDim4EditField.Value = 1;
            app.OrientationSpinnerLabel = uilabel(app.KspacePanel);
            app.OrientationSpinnerLabel.Text = 'Orientation';
            app.KOrientationSpinner = uispinner(app.KspacePanel);
            app.KOrientationSpinner.Limits = [1 6];
            app.KOrientationSpinner.ValueChangedFcn = createCallbackFcn(app, @KOrientationSpinnerValueChanged, true);
            app.KOrientationSpinner.Value = 1;
            app.KxLabel = uilabel(app.KspacePanel);
            app.KxLabel.Text = '     Kx     ';
            app.KyLabel = uilabel(app.KspacePanel);
            app.KyLabel.Text = '     Ky     ';
            app.ButtonGroup = uibuttongroup(app.KspacePanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.fillingButton = uiradiobutton(app.ButtonGroup);
            app.fillingButton.Text = 'filling';
            app.averagesButton = uiradiobutton(app.ButtonGroup);
            app.averagesButton.Text = 'averages';
            app.kvaluesButton = uiradiobutton(app.ButtonGroup);
            app.kvaluesButton.Text = '| k-values |';
            app.kvaluesButton.Value = true;
            app.KDim3Label = uilabel(app.KspacePanel);
            app.KDim3Label.Text = '            Kz';
            app.KEchoSlider = uislider(app.KspacePanel);
            app.KEchoSlider.Limits = [1 200];
            app.KEchoSlider.MajorTicks = [];
            app.KEchoSlider.ValueChangedFcn = createCallbackFcn(app, @KEchoSliderValueChanged, true);
            app.KEchoSlider.MinorTicks = [];
            app.KEchoSlider.Value = 1;
            app.EchoLabel = uilabel(app.KspacePanel);
            app.EchoLabel.Text = 'Echo';
            app.KEchoEditField = uieditfield(app.KspacePanel, 'numeric');
            app.KEchoEditField.Limits = [1 Inf];
            app.KEchoEditField.ValueDisplayFormat = '%.0f';
            app.KEchoEditField.ValueChangedFcn = createCallbackFcn(app, @KEchoEditFieldValueChanged, true);
            app.KEchoEditField.Value = 1;
            app.KDim4Label = uilabel(app.KspacePanel);
            app.KDim4Label.Text = '             NR';
            app.CoilSpinnerLabel = uilabel(app.KspacePanel);
            app.CoilSpinnerLabel.Text = 'Coil';
            app.CoilSpinner = uispinner(app.KspacePanel);
            app.CoilSpinner.Limits = [1 16];
            app.CoilSpinner.ValueDisplayFormat = '%.0f';
            app.CoilSpinner.ValueChangedFcn = createCallbackFcn(app, @CoilSpinnerValueChanged, true);
            app.CoilSpinner.Value = 1;
            app.KFlipAngleSlider = uislider(app.KspacePanel);
            app.KFlipAngleSlider.Limits = [1 200];
            app.KFlipAngleSlider.MajorTicks = [];
            app.KFlipAngleSlider.ValueChangedFcn = createCallbackFcn(app, @KFlipAngleSliderValueChanged, true);
            app.KFlipAngleSlider.MinorTicks = [];
            app.KFlipAngleSlider.Value = 1;
            app.FlipangleLabel = uilabel(app.KspacePanel);
            app.FlipangleLabel.Text = 'Flip angle';
            app.KFlipAngleEditField = uieditfield(app.KspacePanel, 'numeric');
            app.KFlipAngleEditField.Limits = [1 Inf];
            app.KFlipAngleEditField.ValueDisplayFormat = '%.0f';
            app.KFlipAngleEditField.ValueChangedFcn = createCallbackFcn(app, @KFlipAngleEditFieldValueChanged, true);
            app.KFlipAngleEditField.Value = 1;
            app.ScanparametersPanel = uipanel(app.MRDRecoUIFigure);
            app.ScanparametersPanel.Title = 'Scan parameters';
            app.NREditFieldLabel = uilabel(app.ScanparametersPanel);
            app.NREditFieldLabel.Text = 'NR';
            app.NRViewField = uieditfield(app.ScanparametersPanel, 'numeric');
            app.NRViewField.Editable = 'off';
            app.NEEditFieldLabel = uilabel(app.ScanparametersPanel);
            app.NEEditFieldLabel.Text = 'NE';
            app.NEViewField = uieditfield(app.ScanparametersPanel, 'numeric');
            app.NEViewField.Editable = 'off';
            app.KMatrixViewField2 = uieditfield(app.ScanparametersPanel, 'numeric');
            app.KMatrixViewField2.Limits = [1 Inf];
            app.KMatrixViewField2.ValueDisplayFormat = '%.0f';
            app.KMatrixViewField2.Editable = 'off';
            app.KMatrixViewField2.Value = 1;
            app.KMatrixViewField3 = uieditfield(app.ScanparametersPanel, 'numeric');
            app.KMatrixViewField3.Limits = [1 Inf];
            app.KMatrixViewField3.ValueDisplayFormat = '%.0f';
            app.KMatrixViewField3.Editable = 'off';
            app.KMatrixViewField3.Value = 1;
            app.FOVViewField2 = uieditfield(app.ScanparametersPanel, 'numeric');
            app.FOVViewField2.Limits = [0 Inf];
            app.FOVViewField2.ValueDisplayFormat = '%.1f';
            app.FOVViewField2.Editable = 'off';
            app.FOVViewField3 = uieditfield(app.ScanparametersPanel, 'numeric');
            app.FOVViewField3.Limits = [0 Inf];
            app.FOVViewField3.ValueDisplayFormat = '%.1f';
            app.FOVViewField3.Editable = 'off';
            app.MXLabel = uilabel(app.ScanparametersPanel);
            app.MXLabel.Text = 'Kx';
            app.MYLabel = uilabel(app.ScanparametersPanel);
            app.MYLabel.Text = 'Ky';
            app.MZLabel = uilabel(app.ScanparametersPanel);
            app.MZLabel.Text = 'Kz';
            app.MatrixEditFieldLabel = uilabel(app.ScanparametersPanel);
            app.MatrixEditFieldLabel.Text = 'Matrix';
            app.KMatrixViewField1 = uieditfield(app.ScanparametersPanel, 'numeric');
            app.KMatrixViewField1.Limits = [1 Inf];
            app.KMatrixViewField1.ValueDisplayFormat = '%.0f';
            app.KMatrixViewField1.Editable = 'off';
            app.KMatrixViewField1.Value = 1;
            app.FOVmmLabel = uilabel(app.ScanparametersPanel);
            app.FOVmmLabel.Text = 'FOV (mm)';
            app.FOVViewField1 = uieditfield(app.ScanparametersPanel, 'numeric');
            app.FOVViewField1.ValueDisplayFormat = '%.1f';
            app.FOVViewField1.Editable = 'off';
            app.TRmsLabel = uilabel(app.ScanparametersPanel);
            app.TRmsLabel.Text = 'TR (ms)';
            app.TRViewField = uieditfield(app.ScanparametersPanel, 'numeric');
            app.TRViewField.Editable = 'off';
            app.TEmsLabel = uilabel(app.ScanparametersPanel);
            app.TEmsLabel.Text = 'TE (ms)';
            app.TEViewField = uieditfield(app.ScanparametersPanel, 'numeric');
            app.TEViewField.Editable = 'off';
            app.NALabel = uilabel(app.ScanparametersPanel);
            app.NALabel.Text = 'NA';
            app.NAViewField = uieditfield(app.ScanparametersPanel, 'numeric');
            app.NAViewField.Editable = 'off';
            app.NFALabel = uilabel(app.ScanparametersPanel);
            app.NFALabel.Text = 'NFA';
            app.NFAViewField = uieditfield(app.ScanparametersPanel, 'numeric');
            app.NFAViewField.Editable = 'off';
            app.FAdegEditFieldLabel = uilabel(app.ScanparametersPanel);
            app.FAdegEditFieldLabel.Text = 'FA (deg)';
            app.FAViewField = uieditfield(app.ScanparametersPanel, 'text');
            app.FAViewField.Editable = 'off';
            app.SequenceEditFieldLabel = uilabel(app.ScanparametersPanel);
            app.SequenceEditFieldLabel.Text = 'Sequence';
            app.SequenceViewField = uieditfield(app.ScanparametersPanel, 'text');
            app.SequenceViewField.Editable = 'off';
            app.MRDfileLabel = uilabel(app.ScanparametersPanel);
            app.MRDfileLabel.Text = 'MRD file';
            app.MRDfileViewField = uieditfield(app.ScanparametersPanel, 'text');
            app.MRDfileViewField.Editable = 'off';
            app.MessagesPanel = uipanel(app.MRDRecoUIFigure);
            app.MessagesPanel.Title = 'Messages';
            app.MessageBox = uitextarea(app.MessagesPanel);
            app.ReconstructionPanel = uipanel(app.MRDRecoUIFigure);
            app.ReconstructionPanel.Title = 'Reconstruction';
            app.RecoFig = uiaxes(app.ReconstructionPanel);
            title(app.RecoFig, '')
            xlabel(app.RecoFig, '')
            ylabel(app.RecoFig, '')
            app.RecoFig.XTick = [];
            app.RecoFig.YTick = [];
            app.Dim3Slider = uislider(app.ReconstructionPanel);
            app.Dim3Slider.Limits = [1 200];
            app.Dim3Slider.MajorTicks = [];
            app.Dim3Slider.ValueChangedFcn = createCallbackFcn(app, @Dim3SliderValueChanged, true);
            app.Dim3Slider.MinorTicks = [];
            app.Dim3Slider.Value = 1;
            app.Dim5Slider = uislider(app.ReconstructionPanel);
            app.Dim5Slider.Limits = [1 200];
            app.Dim5Slider.MajorTicks = [];
            app.Dim5Slider.ValueChangedFcn = createCallbackFcn(app, @Dim5SliderValueChanged, true);
            app.Dim5Slider.MinorTicks = [];
            app.Dim5Slider.Value = 1;
            app.Dim4Slider = uislider(app.ReconstructionPanel);
            app.Dim4Slider.Limits = [1 200];
            app.Dim4Slider.MajorTicks = [];
            app.Dim4Slider.ValueChangedFcn = createCallbackFcn(app, @Dim4SliderValueChanged, true);
            app.Dim4Slider.MinorTicks = [];
            app.Dim4Slider.Value = 1;
            app.Dim3EditField = uieditfield(app.ReconstructionPanel, 'numeric');
            app.Dim3EditField.Limits = [1 Inf];
            app.Dim3EditField.ValueDisplayFormat = '%.0f';
            app.Dim3EditField.ValueChangedFcn = createCallbackFcn(app, @Dim3EditFieldValueChanged, true);
            app.Dim3EditField.Value = 1;
            app.Dim5EditField = uieditfield(app.ReconstructionPanel, 'numeric');
            app.Dim5EditField.Limits = [1 Inf];
            app.Dim5EditField.ValueDisplayFormat = '%.0f';
            app.Dim5EditField.ValueChangedFcn = createCallbackFcn(app, @Dim5EditFieldValueChanged, true);
            app.Dim5EditField.Value = 1;
            app.Dim4EditField = uieditfield(app.ReconstructionPanel, 'numeric');
            app.Dim4EditField.Limits = [1 Inf];
            app.Dim4EditField.ValueDisplayFormat = '%.0f';
            app.Dim4EditField.ValueChangedFcn = createCallbackFcn(app, @Dim4EditFieldValueChanged, true);
            app.Dim4EditField.Value = 1;
            app.OrientationSpinnerLabel_2 = uilabel(app.ReconstructionPanel);
            app.OrientationSpinnerLabel_2.Text = 'Orientation';
            app.OrientationSpinner = uispinner(app.ReconstructionPanel);
            app.OrientationSpinner.Limits = [1 12];
            app.OrientationSpinner.ValueChangedFcn = createCallbackFcn(app, @OrientationSpinnerValueChanged, true);
            app.OrientationSpinner.Value = 1;
            app.XLabel = uilabel(app.ReconstructionPanel);
            app.XLabel.Text = '     X     ';
            app.YLabel = uilabel(app.ReconstructionPanel);
            app.YLabel.Text = '     Y     ';
            app.Dim6EditField = uieditfield(app.ReconstructionPanel, 'numeric');
            app.Dim6EditField.Limits = [1 Inf];
            app.Dim6EditField.ValueDisplayFormat = '%.0f';
            app.Dim6EditField.ValueChangedFcn = createCallbackFcn(app, @Dim6EditFieldValueChanged, true);
            app.Dim6EditField.Value = 1;
            app.Dim6Slider = uislider(app.ReconstructionPanel);
            app.Dim6Slider.Limits = [1 200];
            app.Dim6Slider.MajorTicks = [];
            app.Dim6Slider.ValueChangedFcn = createCallbackFcn(app, @Dim6SliderValueChanged, true);
            app.Dim6Slider.MinorTicks = [];
            app.Dim6Slider.Value = 1;
            app.Dim3Label = uilabel(app.ReconstructionPanel);
            app.Dim3Label.Text = '              Z';
            app.Dim4Label = uilabel(app.ReconstructionPanel);
            app.Dim4Label.Text = '             NR';
            app.LevelSlider = uislider(app.ReconstructionPanel);
            app.LevelSlider.Limits = [1 4096];
            app.LevelSlider.MajorTicks = [];
            app.LevelSlider.ValueChangedFcn = createCallbackFcn(app, @LevelSliderValueChanged, true);
            app.LevelSlider.MinorTicks = [];
            app.LevelSlider.Value = 1;
            app.WindowLabel = uilabel(app.ReconstructionPanel);
            app.WindowLabel.Text = 'Window';
            app.LevelLabel = uilabel(app.ReconstructionPanel);
            app.LevelLabel.Text = 'Level';
            app.WindowSlider = uislider(app.ReconstructionPanel);
            app.WindowSlider.Limits = [1 4096];
            app.WindowSlider.MajorTicks = [];
            app.WindowSlider.ValueChangedFcn = createCallbackFcn(app, @WindowSliderValueChanged, true);
            app.WindowSlider.MinorTicks = [];
            app.WindowSlider.Value = 1;
            app.LevelViewField = uieditfield(app.ReconstructionPanel, 'numeric');
            app.LevelViewField.Limits = [0 4096];
            app.LevelViewField.ValueDisplayFormat = '%.0f';
            app.WindowViewField = uieditfield(app.ReconstructionPanel, 'numeric');
            app.WindowViewField.Limits = [0 4096];
            app.WindowViewField.ValueDisplayFormat = '%.0f';
            app.SetButton = uibutton(app.ReconstructionPanel, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.PlayButton = uibutton(app.ReconstructionPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.ReconstructionPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.Dim5Label = uilabel(app.ReconstructionPanel);
            app.Dim5Label.Text = 'Flip angle';
            app.Dim6Label = uilabel(app.ReconstructionPanel);
            app.Dim6Label.Text = 'Echo';
            app.AutoIntensityCheckBox = uicheckbox(app.ReconstructionPanel);
            app.AutoIntensityCheckBox.Text = '';
            app.AutoIntensityCheckBox.Value = true;
            app.AutolevelLabel = uilabel(app.ReconstructionPanel);
            app.AutolevelLabel.Text = 'Auto level';
            app.MovieImage = uiimage(app.ReconstructionPanel);
            app.MovieImage.Enable = 'off';
            app.TitleLabel = uilabel(app.MRDRecoUIFigure);
            app.TitleLabel.Text = 'MRD reconstructor';
            app.TulpImage = uiimage(app.MRDRecoUIFigure);
            app.GustavStrijkersAmsterdamUMCVersion12Label = uilabel(app.MRDRecoUIFigure);
            app.GustavStrijkersAmsterdamUMCVersion12Label.Text = 'Gustav Strijkers, Amsterdam UMC, Version 1.2';
            app.MRSImage = uiimage(app.MRDRecoUIFigure);
            % Show the figure after all components are created
            app.MRDRecoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MRDreco

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MRDRecoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MRDRecoUIFigure)
        end
    end
end